classdef ImpedanceCheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        FP1Button    matlab.ui.control.Button
        FPZButton    matlab.ui.control.Button
        FP2Button    matlab.ui.control.Button
        AF7Button    matlab.ui.control.Button
        AF3Button    matlab.ui.control.Button
        GNDButton    matlab.ui.control.Button
        AF4Button    matlab.ui.control.Button
        AF8Button    matlab.ui.control.Button
        F7Button     matlab.ui.control.Button
        F5Button     matlab.ui.control.Button
        F3Button     matlab.ui.control.Button
        F1Button     matlab.ui.control.Button
        FZButton     matlab.ui.control.Button
        F2Button     matlab.ui.control.Button
        F4Button     matlab.ui.control.Button
        F6Button     matlab.ui.control.Button
        F8Button     matlab.ui.control.Button
        FT7Button    matlab.ui.control.Button
        FC5Button    matlab.ui.control.Button
        FC3Button    matlab.ui.control.Button
        FC1Button    matlab.ui.control.Button
        FCZButton    matlab.ui.control.Button
        FC2Button    matlab.ui.control.Button
        FC4Button    matlab.ui.control.Button
        FC6Button    matlab.ui.control.Button
        FT8Button    matlab.ui.control.Button
        T7Button     matlab.ui.control.Button
        C5Button     matlab.ui.control.Button
        C3Button     matlab.ui.control.Button
        C1Button     matlab.ui.control.Button
        CZButton     matlab.ui.control.Button
        C2Button     matlab.ui.control.Button
        C4Button     matlab.ui.control.Button
        C6Button     matlab.ui.control.Button
        T8Button     matlab.ui.control.Button
        TP7Button    matlab.ui.control.Button
        CP5Button    matlab.ui.control.Button
        CP3Button    matlab.ui.control.Button
        CP1Button    matlab.ui.control.Button
        CPZButton    matlab.ui.control.Button
        CP2Button    matlab.ui.control.Button
        CP4Button    matlab.ui.control.Button
        CP6Button    matlab.ui.control.Button
        TP8Button    matlab.ui.control.Button
        P7Button     matlab.ui.control.Button
        P5Button     matlab.ui.control.Button
        P3Button     matlab.ui.control.Button
        P1Button     matlab.ui.control.Button
        PZButton     matlab.ui.control.Button
        P2Button     matlab.ui.control.Button
        P4Button     matlab.ui.control.Button
        P6Button     matlab.ui.control.Button
        P8Button     matlab.ui.control.Button
        PO7Button    matlab.ui.control.Button
        PO5Button    matlab.ui.control.Button
        PO3Button    matlab.ui.control.Button
        POZButton    matlab.ui.control.Button
        PO4Button    matlab.ui.control.Button
        PO6Button    matlab.ui.control.Button
        PO8Button    matlab.ui.control.Button
        O1Button     matlab.ui.control.Button
        OZButton     matlab.ui.control.Button
        O2Button     matlab.ui.control.Button
        M1Button     matlab.ui.control.Button
        M2Button     matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
    end


    properties (Access = private)
        Button_state_array=zeros(64,1); % An array (cell) that stores which electrodes have been pressed. 
        sampler;                      % A object that samples from the REFA
        library;                      % Library of tmsi objects
        impedance_upper_limit=50;        % Impedance Value in kOhm under which the REFA channel lights will turn off
        impedance_array;
        button_list;               %an array (what kind?) to store the text structure names of each electrode button
        periodic_timer              %a timer to regularly automatically update the buttons
        period_time=8;                 % How many seconds between updates. Can't be less than 1.2s while impedances are sampled 5 times (longer if more)
        filename;
    end

    methods (Access = private)
    
        
        % The main loop where code runs, called every period_time (8) seconds by periodic_timer
        function main_loop(app,periodic_timer, event, string_arg)
                              
            %Connect to EEG amplifier 
            connect_device(app);
            
             
           %while(app.UIFigure.Visible=='on')
                
            
            %Check impedances
            try
               app.impedance_array= get_impedances(app);
            
            
            %Display only impedances which are on
                display_impedances(app, app.impedance_array);


            catch 
                disp('error');
                disconnect_device(app);
            
            end
            %Disconnect from device, cleanup library
            disconnect_device(app);
         
            
            %Return to RecordEEG app
            
        end


            %A function to initialize the state of the impedance plot
            function intialize_button_state(app, channel_subset)
           
            %get array  length 
             l=length(channel_subset);
                
                    for i=1:l
                        %set all active buttons on to 1. 
                        app.Button_state_array(channel_subset(i))=1;
                        
                    end 
                    
                    disp(app.Button_state_array);
                    disp('Button_state_array updated');
                    %to be used below
                     channel_list= ["FP1" "FPZ" "FP2" "F7" "F3" "FZ" "F4" "F8" "FC5" "FC1" "FC2" "FC6" "M1" "T7" "C3" "CZ" "C4" "T8" "M2" "CP5" "CP1" "CP2" "CP6" "P7" "P3" "PZ" "P4" "P8" "POZ" "O1" "OZ" "O2" "AF7" "AF3" "AF4" "AF8" "F5" "F1" "F2" "F6" "FC3" "FCZ" "FC4" "C5" "C1" "C2" "CP3" "C6" "CPZ" "CP4" "P5" "P1" "P2" "P6" "PO5" "PO3" "PO4" "PO6" "FT7" "FT8" "TP7" "TP8" "PO7" "PO8"];
         
                    
                    %create structure name vectors for use in get_impedances()
                    for i=1:64
                       app.button_list{i}=sprintf('%sButton', channel_list(i));
                                         
                    end
                        
                    
            end
            
                % A function to initialize the timer that periodically updates the figure 
               function init_timer(app)
                   
                   %init timer with period of 2 seconds, to run forever. 
                   
                   app.periodic_timer = timer('Period',app.period_time,...
                                            'ExecutionMode', 'fixedSpacing', ...
                                            'TasksToExecute', Inf,...
                                            'StartDelay', 2);
                   
                   app.periodic_timer.TimerFcn= @app.main_loop;                       
                                            
                   start(app.periodic_timer);                        
                                            
               end
            
           %A function to connect to the amplifier
            function connect_device(app)
                % Step 1: Setup library and choose connection type ( 'usb', 'bluetooth', 'network' or 'wifi' ).
                app.library = TMSi.Library('usb');

                fprintf(' = Setup library.\n');

                % Step 2: Find device to connect to. Keep on trying every second.
                while numel(app.library.devices) == 0
                    fprintf(' = Refreshing devices.\n');
                    app.library.refreshDevices();
                    pause(1);
                end

                try
                    % Step 3: Get first device an retrieve information about device.
                    device = app.library.getFirstDevice();
                    fprintf(' = First device selected.\n');
                     
                    % Step 4: Create a sampler with which we are going to retrieve samples.
                    app.sampler = device.createSampler();
                    fprintf(' = Sampler created.\n');
                    
                       % Step 5: Set settings for sampler.
                    app.sampler.setImpedanceMode(true);
        
                    
                    catch matlabException
                    warning(sprintf('Error while trying to create device/sampler.\n\tIdentifier: %s\n\tMessage: %s\n', matlabException.identifier, matlabException.message));
                end 
            end
            
            
            %A function to get the impedances from the amplifier
            function impedance_array = get_impedances(app)
                       
                
                impedance_array=zeros(64,1); 
                try
             
                % Step 6: Connect to device through the sampler.
                app.sampler.connect();
                fprintf(' = Sampler connected.\n');
        
                % Step 7: Start imepdance mode sampling.
                app.sampler.start(app.impedance_upper_limit);  %value is kOhm used to control impedance indicators on the device. Valid values are: 2, 5, 10, 20, 50, 100, 200
                fprintf(' = Impedance mode sampling started.\n');
        
                % Step 8: Sample 20times.
                for i=1:20
                    impedance_array = app.sampler.sample();
                    fprintf(' = Sampled %d samples.\n', size(impedance_array, 2));
                end
                
                  % Step 9: Stop sampler.
                app.sampler.stop();
                fprintf(' = Sampling stopped.\n');
                
                  catch matlabException
                    warning(sprintf('Error while trying to sample.\n\tIdentifier: %s\n\tMessage: %s\n', matlabException.identifier, matlabException.message));
                end
                
            end 
            
            function disconnect_device(app)
                       
                % Step 9: Stop sampler.
                app.sampler.stop();
                fprintf(' = Sampling stopped.\n');
            
                % Step 10: Disconnect with device.
                app.sampler.disconnect();
                fprintf(' = Sampler disconnected.\n');
                    
                % Step 11: Cleanup library.
                app.library.destroy();
                fprintf(' = Destroy library.\n');
            end
            
                       %A function that returns the ANT waveguard channel number of a given name
           function channel_number = get_channel_number(~, channel_name)
                
                   channel_list= ["FP1" "FPZ" "FP2" "F7" "F3" "FZ" "F4" "F8" "FC5" "FC1" "FC2" "FC6" "M1" "T7" "C3" "CZ" "C4" "T8" "M2" "CP5" "CP1" "CP2" "CP6" "P7" "P3" "PZ" "P4" "P8" "POZ" "O1" "OZ" "O2" "AF7" "AF3" "AF4" "AF8" "F5" "F1" "F2" "F6" "FC3" "FCZ" "FC4" "C5" "C1" "C2" "CP3" "C6" "CPZ" "CP4" "P5" "P1" "P2" "P6" "PO5" "PO3" "PO4" "PO6" "FT7" "FT8" "TP7" "TP8" "PO7" "PO8"];
                            %get index of the cell with specified string
                   channel_number= find(strcmp(channel_list, channel_name));
                    
           end
                   
                   
         function channel_name = get_channel_name(~, channel_number)
                   
                   channel_list= ["FP1" "FPZ" "FP2" "F7" "F3" "FZ" "F4" "F8" "FC5" "FC1" "FC2" "FC6" "M1" "T7" "C3" "CZ" "C4" "T8" "M2" "CP5" "CP1" "CP2" "CP6" "P7" "P3" "PZ" "P4" "P8" "POZ" "O1" "OZ" "O2" "AF7" "AF3" "AF4" "AF8" "F5" "F1" "F2" "F6" "FC3" "FCZ" "FC4" "C5" "C1" "C2" "CP3" "C6" "CPZ" "CP4" "P5" "P1" "P2" "P6" "PO5" "PO3" "PO4" "PO6" "FT7" "FT8" "TP7" "TP8" "PO7" "PO8"];
                   channel_name= channel_list(channel_number);
                   
         end
            
         %A function to update the on/off Button_state_array when a particular button is pressed. 
        function update_electrode_state(app, channel_name)
            %Toggle value of array that corresponds to the channel name. 
            if(app.Button_state_array(get_channel_number(app, channel_name)) == 0)
                app.Button_state_array(get_channel_number(app, channel_name)) = 1;
                
            else 
                app.Button_state_array(get_channel_number(app, channel_name)) = 0;
            end
            %test show new states
            disp('Button_state_array updated');
        end  


        %Displays impedances on button text and changes colours of button
        function display_impedances(app, impedance_array)
            
            %impedance limits for changing button colour 
            green_impedance=5;
           % yellow_impedance=green_impedance*2;
            red_impedance=green_impedance*3;
            
            %colours for reference: 
            red = [1 0 0];
            yellow = [1 1 0];
            green = [0 1 0];
            grey = [0.8 0.8 0.8];
            
                              
            %iterate through all channels (faster way to do this?)
            for i=1:64
                
                %Change the colour and text of all 'ON' buttons
                if app.Button_state_array(i) ==1
                   
                                  
                  set(app.(app.button_list{i}), 'Text', num2str(impedance_array(i)));
                    
                    if(impedance_array(i)>=red_impedance)
                       
                        set(app.(app.button_list{i}), 'BackgroundColor', red);
                       
                    elseif((impedance_array(i)<red_impedance)&&(impedance_array(i)>green_impedance))
                      
                        set(app.(app.button_list{i}), 'BackgroundColor', yellow);
                    elseif(impedance_array(i)<=green_impedance)
                       
                        set(app.(app.button_list{i}), 'BackgroundColor', green);
                    end                  
                    
                else %set all off buttons to grey
                    set(app.(app.button_list{i}), 'BackgroundColor', grey);
                
                end
            end         
        end 
        
        %a function to save impedance values into a file for posterity 
        function save_file(app, impedance_array)
        
            %take active electrodes from Button_state_array
            %app.Button_state_array=
            
            channel_list= ["FP1" "FPZ" "FP2" "F7" "F3" "FZ" "F4" "F8" "FC5" "FC1" "FC2" "FC6" "M1" "T7" "C3" "CZ" "C4" "T8" "M2" "CP5" "CP1" "CP2" "CP6" "P7" "P3" "PZ" "P4" "P8" "POZ" "O1" "OZ" "O2" "AF7" "AF3" "AF4" "AF8" "F5" "F1" "F2" "F6" "FC3" "FCZ" "FC4" "C5" "C1" "C2" "CP3" "C6" "CPZ" "CP4" "P5" "P1" "P2" "P6" "PO5" "PO3" "PO4" "PO6" "FT7" "FT8" "TP7" "TP8" "PO7" "PO8"];
                   
            %make impedance_filename
            impedance_filename=sprintf('Impedance_%s.txt', app.filename);
            
            %write to file
            fileID = fopen(impedance_filename,'w');
            
            %Data header
            fprintf(fileID,'%12s %25s\n','Channel_Name','Channel_Impedance(kOhms)');
            for i=1:64
                
                %Change the colour and text of all 'ON' buttons
                if app.Button_state_array(i) ==1
                    fprintf(fileID,'%s %4i\n',channel_list(i),impedance_array(i));
                    
                end
            end
                    fclose(fileID);
            
            
        end
        
    end %end of functions


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, channel_subset, file_name, varargin)
            
%             %Get which electrodes are on. Populates app.Button_state_array with 1's for electrodes that are on
            intialize_button_state(app, channel_subset);
            disp(channel_subset);
            
            %save filename for use in impedance filename
            app.filename=file_name;
            
            init_timer(app);
     
            
        end

        % Button pushed function: FP1Button
        function FP1ButtonPushed(app, event)
            %toggle on/off value of state array
            %1
            update_electrode_state(app, 'FP1'); %1
        end

        % Button pushed function: FPZButton
        function FPZButtonPushed(app, event)
            update_electrode_state(app, 'FPZ'); %2
        end

        % Button pushed function: FP2Button
        function FP2ButtonPushed(app, event)
            update_electrode_state(app,'FP2'); %3
        end

        % Button pushed function: AF7Button
        function AF7ButtonPushed(app, event)
            update_electrode_state(app,'AF7'); %4
        end

        % Button pushed function: AF3Button
        function AF3ButtonPushed(app, event)
            update_electrode_state(app, 'AF3'); %5
        end

        % Button pushed function: GNDButton
        function GNDButtonPushed(app, event)
            update_electrode_state(app,'AFZ'); %6
        end

        % Button pushed function: AF4Button
        function AF4ButtonPushed(app, event)
            update_electrode_state(app, 'AF4'); %6
        end

        % Button pushed function: AF8Button
        function AF8ButtonPushed(app, event)
            update_electrode_state(app, 'AF8'); %7
        end

        % Button pushed function: F7Button
        function F7ButtonPushed(app, event)
            update_electrode_state(app, 'F7'); %8
        end

        % Button pushed function: F5Button
        function F5ButtonPushed(app, event)
            update_electrode_state(app, 'F5'); %9
        end

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
            update_electrode_state(app, 'F3'); %10
        end

        % Button pushed function: F1Button
        function F1ButtonPushed(app, event)
            update_electrode_state(app, 'F1'); %11
        end

        % Button pushed function: FZButton
        function FZButtonPushed(app, event)
            update_electrode_state(app, 'FZ');
        end

        % Button pushed function: F2Button
        function F2ButtonPushed(app, event)
            update_electrode_state(app, 'F2');
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            update_electrode_state(app, 'F4');
        end

        % Button pushed function: F6Button
        function F6ButtonPushed(app, event)
            update_electrode_state(app, 'F6');
        end

        % Button pushed function: F8Button
        function F8ButtonPushed(app, event)
            update_electrode_state(app, 'F8');
        end

        % Button pushed function: FT7Button
        function FT7ButtonPushed(app, event)
            update_electrode_state(app, 'FT7');
        end

        % Button pushed function: FC5Button
        function FC5ButtonPushed(app, event)
            update_electrode_state(app, 'FC5');
        end

        % Button pushed function: FC3Button
        function FC3ButtonPushed(app, event)
            update_electrode_state(app, 'FC3');
        end

        % Button pushed function: FC1Button
        function FC1ButtonPushed(app, event)
            update_electrode_state(app, 'FC1');
        end

        % Button pushed function: FCZButton
        function FCZButtonPushed(app, event)
            update_electrode_state(app, 'FCZ');
        end

        % Button pushed function: FC2Button
        function FC2ButtonPushed(app, event)
            update_electrode_state(app, 'FC2');
        end

        % Button pushed function: FC4Button
        function FC4ButtonPushed(app, event)
            update_electrode_state(app, 'FC4');
        end

        % Button pushed function: FC6Button
        function FC6ButtonPushed(app, event)
            update_electrode_state(app, 'FC6');
        end

        % Button pushed function: FT8Button
        function FT8ButtonPushed(app, event)
            update_electrode_state(app, 'FT8');
        end

        % Button pushed function: T7Button
        function T7ButtonPushed(app, event)
            update_electrode_state(app, 'T7');
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            update_electrode_state(app, 'C5');
        end

        % Button pushed function: C3Button
        function C3ButtonPushed(app, event)
            update_electrode_state(app, 'C3');
        end

        % Button pushed function: C1Button
        function C1ButtonPushed(app, event)
            update_electrode_state(app, 'C1');
        end

        % Button pushed function: CZButton
        function CZButtonPushed(app, event)
            update_electrode_state(app, 'CZ');
        end

        % Button pushed function: C2Button
        function C2ButtonPushed(app, event)
            update_electrode_state(app, 'C2');
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            update_electrode_state(app, 'C4');
        end

        % Button pushed function: C6Button
        function C6ButtonPushed(app, event)
            update_electrode_state(app, 'C6');
        end

        % Button pushed function: T8Button
        function T8ButtonPushed(app, event)
            update_electrode_state(app, 'T8');
        end

        % Button pushed function: TP7Button
        function TP7ButtonPushed(app, event)
            update_electrode_state(app, 'TP7');
        end

        % Button pushed function: CP5Button
        function CP5ButtonPushed(app, event)
            update_electrode_state(app, 'CP5');
        end

        % Button pushed function: CP3Button
        function CP3ButtonPushed(app, event)
            update_electrode_state(app, 'CP3');
        end

        % Button pushed function: CP1Button
        function CP1ButtonPushed(app, event)
            update_electrode_state(app, 'CP1');
        end

        % Button pushed function: CPZButton
        function CPZButtonPushed(app, event)
            update_electrode_state(app, 'CPZ');
        end

        % Button pushed function: CP2Button
        function CP2ButtonPushed(app, event)
            update_electrode_state(app, 'CP2');
        end

        % Button pushed function: CP4Button
        function CP4ButtonPushed(app, event)
            update_electrode_state(app, 'CP4');
        end

        % Button pushed function: CP6Button
        function CP6ButtonPushed(app, event)
            update_electrode_state(app, 'CP6');
        end

        % Button pushed function: TP8Button
        function TP8ButtonPushed(app, event)
            update_electrode_state(app, 'TP8');
        end

        % Button pushed function: P7Button
        function P7ButtonPushed(app, event)
            update_electrode_state(app, 'P7');
        end

        % Button pushed function: P5Button
        function P5ButtonPushed(app, event)
            update_electrode_state(app, 'P5');
        end

        % Button pushed function: P3Button
        function P3ButtonPushed(app, event)
            update_electrode_state(app, 'P3');
        end

        % Button pushed function: P1Button
        function P1ButtonPushed(app, event)
            update_electrode_state(app, 'P1');
        end

        % Button pushed function: PZButton
        function PZButtonPushed(app, event)
            update_electrode_state(app, 'PZ');
        end

        % Button pushed function: P2Button
        function P2ButtonPushed(app, event)
            update_electrode_state(app, 'P2');
        end

        % Button pushed function: P4Button
        function P4ButtonPushed(app, event)
            update_electrode_state(app, 'P4');
        end

        % Button pushed function: P6Button
        function P6ButtonPushed(app, event)
            update_electrode_state(app, 'P6');
        end

        % Button pushed function: P8Button
        function P8ButtonPushed(app, event)
            update_electrode_state(app, 'P8');
        end

        % Button pushed function: PO7Button
        function PO7ButtonPushed(app, event)
            update_electrode_state(app, 'PO7');
        end

        % Button pushed function: PO5Button
        function PO5ButtonPushed(app, event)
            update_electrode_state(app, 'PO5');
        end

        % Button pushed function: PO3Button
        function PO3ButtonPushed(app, event)
            update_electrode_state(app, 'PO3');
        end

        % Button pushed function: POZButton
        function POZButtonPushed(app, event)
            update_electrode_state(app, 'POZ');
        end

        % Button pushed function: PO4Button
        function PO4ButtonPushed(app, event)
            update_electrode_state(app, 'PO4');
        end

        % Button pushed function: PO6Button
        function PO6ButtonPushed(app, event)
            update_electrode_state(app, 'PO6');
        end

        % Button pushed function: PO8Button
        function PO8ButtonPushed(app, event)
            update_electrode_state(app, 'PO8');
        end

        % Button pushed function: O1Button
        function O1ButtonPushed(app, event)
            update_electrode_state(app, 'O1');
        end

        % Button pushed function: OZButton
        function OZButtonPushed(app, event)
            update_electrode_state(app, 'OZ');
        end

        % Button pushed function: O2Button
        function O2ButtonPushed(app, event)
            update_electrode_state(app, 'O2');
        end

        % Button pushed function: M1Button
        function M1ButtonPushed(app, event)
            update_electrode_state(app, 'M1');
        end

        % Button pushed function: M2Button
        function M2ButtonPushed(app, event)
            update_electrode_state(app, 'M2');
        end

        % Callback function: StopButton, UIFigure
        function UIFigureCloseRequest(app, event)
           %Call main app's public function?
                    %delete timer. 
                    %Save impedances to file for posterity
                    save_file(app, app.impedance_array);
                    stop(app.periodic_timer);
                    delete(app.periodic_timer);
                    disconnect_device(app);
                    delete(app)
                    
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
               %Run as normal if someone presses it. 
            main_loop(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'waveguard_layout_064ch.png';
            app.Button.Text = '';
            app.FP1Button = uibutton(app.UIFigure, 'push');
            app.FP1Button.ButtonPushedFcn = createCallbackFcn(app, @FP1ButtonPushed, true);
            app.FP1Button.Text = 'FP1';
            app.FPZButton = uibutton(app.UIFigure, 'push');
            app.FPZButton.ButtonPushedFcn = createCallbackFcn(app, @FPZButtonPushed, true);
            app.FPZButton.Text = 'FPZ';
            app.FP2Button = uibutton(app.UIFigure, 'push');
            app.FP2Button.ButtonPushedFcn = createCallbackFcn(app, @FP2ButtonPushed, true);
            app.FP2Button.Text = 'FP2';
            app.AF7Button = uibutton(app.UIFigure, 'push');
            app.AF7Button.ButtonPushedFcn = createCallbackFcn(app, @AF7ButtonPushed, true);
            app.AF7Button.Text = 'AF7';
            app.AF3Button = uibutton(app.UIFigure, 'push');
            app.AF3Button.ButtonPushedFcn = createCallbackFcn(app, @AF3ButtonPushed, true);
            app.AF3Button.Text = 'AF3';
            app.GNDButton = uibutton(app.UIFigure, 'push');
            app.GNDButton.ButtonPushedFcn = createCallbackFcn(app, @GNDButtonPushed, true);
            app.GNDButton.Text = 'GND';
            app.AF4Button = uibutton(app.UIFigure, 'push');
            app.AF4Button.ButtonPushedFcn = createCallbackFcn(app, @AF4ButtonPushed, true);
            app.AF4Button.Text = 'AF4';
            app.AF8Button = uibutton(app.UIFigure, 'push');
            app.AF8Button.ButtonPushedFcn = createCallbackFcn(app, @AF8ButtonPushed, true);
            app.AF8Button.Text = 'AF8';
            app.F7Button = uibutton(app.UIFigure, 'push');
            app.F7Button.ButtonPushedFcn = createCallbackFcn(app, @F7ButtonPushed, true);
            app.F7Button.Text = 'F7';
            app.F5Button = uibutton(app.UIFigure, 'push');
            app.F5Button.ButtonPushedFcn = createCallbackFcn(app, @F5ButtonPushed, true);
            app.F5Button.Text = 'F5';
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.F1Button = uibutton(app.UIFigure, 'push');
            app.F1Button.ButtonPushedFcn = createCallbackFcn(app, @F1ButtonPushed, true);
            app.F1Button.Text = 'F1';
            app.FZButton = uibutton(app.UIFigure, 'push');
            app.FZButton.ButtonPushedFcn = createCallbackFcn(app, @FZButtonPushed, true);
            app.FZButton.Text = 'FZ';
            app.F2Button = uibutton(app.UIFigure, 'push');
            app.F2Button.ButtonPushedFcn = createCallbackFcn(app, @F2ButtonPushed, true);
            app.F2Button.Text = 'F2';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.F6Button = uibutton(app.UIFigure, 'push');
            app.F6Button.ButtonPushedFcn = createCallbackFcn(app, @F6ButtonPushed, true);
            app.F6Button.Text = 'F6';
            app.F8Button = uibutton(app.UIFigure, 'push');
            app.F8Button.ButtonPushedFcn = createCallbackFcn(app, @F8ButtonPushed, true);
            app.F8Button.Text = 'F8';
            app.FT7Button = uibutton(app.UIFigure, 'push');
            app.FT7Button.ButtonPushedFcn = createCallbackFcn(app, @FT7ButtonPushed, true);
            app.FT7Button.Text = 'FT7';
            app.FC5Button = uibutton(app.UIFigure, 'push');
            app.FC5Button.ButtonPushedFcn = createCallbackFcn(app, @FC5ButtonPushed, true);
            app.FC5Button.Text = 'FC5';
            app.FC3Button = uibutton(app.UIFigure, 'push');
            app.FC3Button.ButtonPushedFcn = createCallbackFcn(app, @FC3ButtonPushed, true);
            app.FC3Button.Text = 'FC3';
            app.FC1Button = uibutton(app.UIFigure, 'push');
            app.FC1Button.ButtonPushedFcn = createCallbackFcn(app, @FC1ButtonPushed, true);
            app.FC1Button.Text = 'FC1';
            app.FCZButton = uibutton(app.UIFigure, 'push');
            app.FCZButton.ButtonPushedFcn = createCallbackFcn(app, @FCZButtonPushed, true);
            app.FCZButton.Text = 'FCZ';
            app.FC2Button = uibutton(app.UIFigure, 'push');
            app.FC2Button.ButtonPushedFcn = createCallbackFcn(app, @FC2ButtonPushed, true);
            app.FC2Button.Text = 'FC2';
            app.FC4Button = uibutton(app.UIFigure, 'push');
            app.FC4Button.ButtonPushedFcn = createCallbackFcn(app, @FC4ButtonPushed, true);
            app.FC4Button.Text = 'FC4';
            app.FC6Button = uibutton(app.UIFigure, 'push');
            app.FC6Button.ButtonPushedFcn = createCallbackFcn(app, @FC6ButtonPushed, true);
            app.FC6Button.Text = 'FC6';
            app.FT8Button = uibutton(app.UIFigure, 'push');
            app.FT8Button.ButtonPushedFcn = createCallbackFcn(app, @FT8ButtonPushed, true);
            app.FT8Button.Text = 'FT8';
            app.T7Button = uibutton(app.UIFigure, 'push');
            app.T7Button.ButtonPushedFcn = createCallbackFcn(app, @T7ButtonPushed, true);
            app.T7Button.Text = 'T7';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.C3Button = uibutton(app.UIFigure, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.C3Button.Text = 'C3';
            app.C1Button = uibutton(app.UIFigure, 'push');
            app.C1Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C1Button.Text = 'C1';
            app.CZButton = uibutton(app.UIFigure, 'push');
            app.CZButton.ButtonPushedFcn = createCallbackFcn(app, @CZButtonPushed, true);
            app.CZButton.Text = 'CZ';
            app.C2Button = uibutton(app.UIFigure, 'push');
            app.C2Button.ButtonPushedFcn = createCallbackFcn(app, @C2ButtonPushed, true);
            app.C2Button.Text = 'C2';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.C6Button = uibutton(app.UIFigure, 'push');
            app.C6Button.ButtonPushedFcn = createCallbackFcn(app, @C6ButtonPushed, true);
            app.C6Button.Text = 'C6';
            app.T8Button = uibutton(app.UIFigure, 'push');
            app.T8Button.ButtonPushedFcn = createCallbackFcn(app, @T8ButtonPushed, true);
            app.T8Button.Text = 'T8';
            app.TP7Button = uibutton(app.UIFigure, 'push');
            app.TP7Button.ButtonPushedFcn = createCallbackFcn(app, @TP7ButtonPushed, true);
            app.TP7Button.Text = 'TP7';
            app.CP5Button = uibutton(app.UIFigure, 'push');
            app.CP5Button.ButtonPushedFcn = createCallbackFcn(app, @CP5ButtonPushed, true);
            app.CP5Button.Text = 'CP5';
            app.CP3Button = uibutton(app.UIFigure, 'push');
            app.CP3Button.ButtonPushedFcn = createCallbackFcn(app, @CP3ButtonPushed, true);
            app.CP3Button.Text = 'CP3';
            app.CP1Button = uibutton(app.UIFigure, 'push');
            app.CP1Button.ButtonPushedFcn = createCallbackFcn(app, @CP1ButtonPushed, true);
            app.CP1Button.Text = 'CP1';
            app.CPZButton = uibutton(app.UIFigure, 'push');
            app.CPZButton.ButtonPushedFcn = createCallbackFcn(app, @CPZButtonPushed, true);
            app.CPZButton.Text = 'CPZ';
            app.CP2Button = uibutton(app.UIFigure, 'push');
            app.CP2Button.ButtonPushedFcn = createCallbackFcn(app, @CP2ButtonPushed, true);
            app.CP2Button.Text = 'CP2';
            app.CP4Button = uibutton(app.UIFigure, 'push');
            app.CP4Button.ButtonPushedFcn = createCallbackFcn(app, @CP4ButtonPushed, true);
            app.CP4Button.Text = 'CP4';
            app.CP6Button = uibutton(app.UIFigure, 'push');
            app.CP6Button.ButtonPushedFcn = createCallbackFcn(app, @CP6ButtonPushed, true);
            app.CP6Button.Text = 'CP6';
            app.TP8Button = uibutton(app.UIFigure, 'push');
            app.TP8Button.ButtonPushedFcn = createCallbackFcn(app, @TP8ButtonPushed, true);
            app.TP8Button.Text = 'TP8';
            app.P7Button = uibutton(app.UIFigure, 'push');
            app.P7Button.ButtonPushedFcn = createCallbackFcn(app, @P7ButtonPushed, true);
            app.P7Button.Text = 'P7';
            app.P5Button = uibutton(app.UIFigure, 'push');
            app.P5Button.ButtonPushedFcn = createCallbackFcn(app, @P5ButtonPushed, true);
            app.P5Button.Text = 'P5';
            app.P3Button = uibutton(app.UIFigure, 'push');
            app.P3Button.ButtonPushedFcn = createCallbackFcn(app, @P3ButtonPushed, true);
            app.P3Button.Text = 'P3';
            app.P1Button = uibutton(app.UIFigure, 'push');
            app.P1Button.ButtonPushedFcn = createCallbackFcn(app, @P1ButtonPushed, true);
            app.P1Button.Text = 'P1';
            app.PZButton = uibutton(app.UIFigure, 'push');
            app.PZButton.ButtonPushedFcn = createCallbackFcn(app, @PZButtonPushed, true);
            app.PZButton.Text = 'PZ';
            app.P2Button = uibutton(app.UIFigure, 'push');
            app.P2Button.ButtonPushedFcn = createCallbackFcn(app, @P2ButtonPushed, true);
            app.P2Button.Text = 'P2';
            app.P4Button = uibutton(app.UIFigure, 'push');
            app.P4Button.ButtonPushedFcn = createCallbackFcn(app, @P4ButtonPushed, true);
            app.P4Button.Text = 'P4';
            app.P6Button = uibutton(app.UIFigure, 'push');
            app.P6Button.ButtonPushedFcn = createCallbackFcn(app, @P6ButtonPushed, true);
            app.P6Button.Text = 'P6';
            app.P8Button = uibutton(app.UIFigure, 'push');
            app.P8Button.ButtonPushedFcn = createCallbackFcn(app, @P8ButtonPushed, true);
            app.P8Button.Text = 'P8';
            app.PO7Button = uibutton(app.UIFigure, 'push');
            app.PO7Button.ButtonPushedFcn = createCallbackFcn(app, @PO7ButtonPushed, true);
            app.PO7Button.Text = 'PO7';
            app.PO5Button = uibutton(app.UIFigure, 'push');
            app.PO5Button.ButtonPushedFcn = createCallbackFcn(app, @PO5ButtonPushed, true);
            app.PO5Button.Text = 'PO5';
            app.PO3Button = uibutton(app.UIFigure, 'push');
            app.PO3Button.ButtonPushedFcn = createCallbackFcn(app, @PO3ButtonPushed, true);
            app.PO3Button.Text = 'PO3';
            app.POZButton = uibutton(app.UIFigure, 'push');
            app.POZButton.ButtonPushedFcn = createCallbackFcn(app, @POZButtonPushed, true);
            app.POZButton.Text = 'POZ';
            app.PO4Button = uibutton(app.UIFigure, 'push');
            app.PO4Button.ButtonPushedFcn = createCallbackFcn(app, @PO4ButtonPushed, true);
            app.PO4Button.Text = 'PO4';
            app.PO6Button = uibutton(app.UIFigure, 'push');
            app.PO6Button.ButtonPushedFcn = createCallbackFcn(app, @PO6ButtonPushed, true);
            app.PO6Button.Text = 'PO6';
            app.PO8Button = uibutton(app.UIFigure, 'push');
            app.PO8Button.ButtonPushedFcn = createCallbackFcn(app, @PO8ButtonPushed, true);
            app.PO8Button.Text = 'PO8';
            app.O1Button = uibutton(app.UIFigure, 'push');
            app.O1Button.ButtonPushedFcn = createCallbackFcn(app, @O1ButtonPushed, true);
            app.O1Button.Text = 'O1';
            app.OZButton = uibutton(app.UIFigure, 'push');
            app.OZButton.ButtonPushedFcn = createCallbackFcn(app, @OZButtonPushed, true);
            app.OZButton.Text = 'OZ';
            app.O2Button = uibutton(app.UIFigure, 'push');
            app.O2Button.ButtonPushedFcn = createCallbackFcn(app, @O2ButtonPushed, true);
            app.O2Button.Text = 'O2';
            app.M1Button = uibutton(app.UIFigure, 'push');
            app.M1Button.ButtonPushedFcn = createCallbackFcn(app, @M1ButtonPushed, true);
            app.M1Button.Text = 'M1';
            app.M2Button = uibutton(app.UIFigure, 'push');
            app.M2Button.ButtonPushedFcn = createCallbackFcn(app, @M2ButtonPushed, true);
            app.M2Button.Text = 'M2';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImpedanceCheck(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end