classdef RecordEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordEEGUIFigure              matlab.ui.Figure
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        CheckImpedancesButton          matlab.ui.control.Button
        PlotAveragesListBoxLabel       matlab.ui.control.Label
        PlotAveragesListBox            matlab.ui.control.ListBox
        ChannelSubsetListBoxLabel      matlab.ui.control.Label
        ChannelSubsetListBox           matlab.ui.control.ListBox
        EEGLABAnalysesDropDownLabel    matlab.ui.control.Label
        EEGLABAnalysesDropDown         matlab.ui.control.DropDown
        OpenEEGLABButton               matlab.ui.control.Button
        StartRecordingButton           matlab.ui.control.Button
        ConnecttoEEGButton             matlab.ui.control.Button
        ConnectedLamp                  matlab.ui.control.Lamp
        ParticipantIDEditFieldLabel    matlab.ui.control.Label
        ParticipantIDEditField         matlab.ui.control.EditField
        TrialConditionEditFieldLabel   matlab.ui.control.Label
        TrialConditionEditField        matlab.ui.control.EditField
        SaveFileButton                 matlab.ui.control.Button
        soundtestButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Start30minTimerButton          matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        FoldertoProcessEditFieldLabel  matlab.ui.control.Label
        FoldertoProcessEditField       matlab.ui.control.EditField
        GoButton                       matlab.ui.control.Button
        DominantHandDropDownLabel      matlab.ui.control.Label
        DominantHandDropDown           matlab.ui.control.DropDown
    end


    properties (Access = private)
        device;                      % TMSi device to connect to 
        library
        sampler
        
        %updates file path to current matlab path
        filepath=path(   );
        
        %the default folder where files will be saved
        default_path;
        
        %variables for sound
        y
        Fs
        
        %object for timer
        thirty_timer
        major_minutes=30;   %the total number of minutes before the timer ends
        minor_minutes=5;    %the number of minutes between reminders to rate your pain. 
        counter=0;        % a counter to turn off th etimer. 
        
        
    end

    properties (Access = public)
        file_name                   % Name of stored file
        measurement_name            % Name of the specific measurement situation
        channel_subset=[1 3 4 5 6 7 8 14 15 16 17 18 24 25 26 27 28 30 32 73 74];             % Channels to record/visualize
        channel_list=["FP1" "FPZ" "FP2" "F7" "F3" "FZ" "F4" "F8" "FC5" "FC1" "FC2" "FC6" "M1" "T7" "C3" "CZ" "C4" "T8" "M2" "CP5" "CP1" "CP2" "CP6" "P7" "P3" "PZ" "P4" "P8" "POZ" "O1" "OZ" "O2" "AF7" "AF3" "AF4" "AF8" "F5" "F1" "F2" "F6" "FC3" "FCZ" "FC4" "C5" "C1" "C2" "C6" "CP3" "CPZ" "CP4" "P5" "P1" "P2" "P6" "PO5" "PO3" "PO4" "PO6" "FT7" "FT8" "TP7" "TP8" "PO7"];
           
        average_subset   =16;           % Channels to average upon event
        avgsum=[];
        avgnum;
    end

    methods (Access = private)
    
      
        %A function that returns the ANT waveguard channel number of a given name
        function channel_number = get_channel_number(~, channel_name)
             %this array of strings is fine in Matlab versions after 2017a. before that will need ['FP1','FPZ',...]
           %channel_list= ["FP1" "FPZ" "FP2" "F7" "F3" "FZ" "F4" "F8" "FC5" "FC1" "FC2" "FC6" "M1" "T7" "C3" "C7" "C4" "T8" "M2" "CP5" "CP1" "CP2" "CP6" "P7" "P3" "PZ" "P4" "P8" "POZ" "O1" "OZ" "O2" "AF7" "AF3" "AF4" "AF8" "F5" "F1" "F2" "F6" "FC3" "FC7" "FC4" "C5" "C1" "C2" "CP3" "C6" "CZ" "CP4" "P5" "P1" "P2" "P6" "PO5" "PO3" "PO4" "PO6" "FT7" "FT8" "TP7" "TP8" "PO7"];
           
           channel_number=strfind(app.channel_list, channel_name);
            
        end


        function record(app)
            
            app.file_name = strcat(app.FilenameEditField.Value,'.poly5');
   	        % name of measurement:
            app.measurement_name = app.FilenameEditField.Value;
            
            % Step 1: Setup library and choose connection type ( 'usb', 'bluetooth', 'network' or 'wifi' ).
            app.library = TMSi.Library('usb');
            
            % Step 2: Find device to connect to. Keep on trying every second.
            while numel(app.library.devices) == 0
                app.library.refreshDevices();
                pause(1);
            end
            
            try
                % Step 3: Get first device an retrieve information about device.
                app.device = app.library.getFirstDevice();
                
                % Step 4: Create a sampler with which we are going to retrieve samples.
                app.sampler = app.device.createSampler();
                
                try
                    % Step 5: Set settings for sampler.
                    % sampler.setSampleRate(2048);
                    % sampler.setReferenceCalculation(true);
                    
                    % Step 6: Create a RealTimePlot.
                    realTimePlot = TMSi.RealTimePlot('RealTimePlot Example', app.sampler.sample_rate, app.device.channels(app.channel_subset));
                    realTimePlot.setWindowSize(10);
                    realTimePlot.show();
                    
                    % Step 7: Create a Poly5 file to save samples to.
                    poly5 = TMSi.Poly5(app.file_name, app.measurement_name, app.sampler.sample_rate, app.device.channels(app.channel_subset));
                   
                    
                    % Step 8: Connect to device through the sampler.
                    app.sampler.connect();
                    
                    % Step 9: Start sampling.
                    app.sampler.start();
                    
                    % turn on lamp
                    app.ConnectedLamp.Color= [0 1 0];
                    
                    %Setup parameters for live-average functionality 
                    window_size = 3;
                    window_sec = 3;
                    %event_location=app.sampler.sample_rate; %want to check it after 1 second, and have the rest of the buffer (2 seconds) display
                    %how many data points to graph
                    window_samples = floor(window_sec * app.sampler.sample_rate);
                    sample_buffer = zeros(app.device.num_channels, window_samples);
                    sample_buffer(73, 1:app.sampler.sample_rate)=255;
                    count      = 0;
                    
                    %initialize timelock cell-array. Each cell will hold the average in one
                    %condition
                    app.avgsum= zeros(app.device.num_channels, window_samples);
                    app.avgnum=0;
                    average= [];
                    %a variable to index the sample_buffer once an event has occured.
                    index=app.sampler.sample_rate+1;
                    plot_flag=0;
                        %a flag to tell when a falling edge has occured on the digi channel. 
                    event_happening=0;
                    
                    %One time allocation of time (eg 0:120000)
                    %time=[((-app.sampler.sample_rate+1):1:0) (1:1:app.sampler.sample_rate*2)]; 
                    
                    time=[(0:1:(app.sampler.sample_rate*3-1))]; 
                    
                    fig_handle = uifigure;
                    %Avg_fig_handle.xlim=([-1 2]);
                    
                    ax=uiaxes(fig_handle, 'YDir', 'normal');
                   % app.UIAxes.YDir = 'reverse';
                    ax.XLim=[0 app.sampler.sample_rate*3];
                    ax.XTick= [0 app.sampler.sample_rate/2 app.sampler.sample_rate app.sampler.sample_rate*3/2 app.sampler.sample_rate*2 app.sampler.sample_rate*5/2 app.sampler.sample_rate*3];
                    ax.XTickLabel={'-1' '-0.5' '0' '0.5' '1' '1.5' '2'};
                    ax.XLabel.String='ERP Epoch (s)';
                    ax.YLabel.String='Electrode Voltage (uV)';


                    % Step 10: Sample as long as plot is visible. Can be closed with 'q' or cross.
                    while realTimePlot.is_visible
                        samples = app.sampler.sample();
                        %increment count
                        count=count+1;


                        %Check if event happened in THIS sample-cycle.
                        if((samples(73,end)<255)&&event_happening==0)
                            event_happening=1;
                            
                        end
                        
                        %Populate array to check if sample_buffer has been completely
                        %filled. Dim: 1x74.
                        buffer_full=all(sample_buffer(:,1:end),2);
                        
                        
                        if(event_happening==0)
                            %Continue overwriting the first 4000 samples of the buffer.  No
                            %need to flip the samples (newest is on the right)
                            
                            %Circshift writes rows backward by the size of samples. Old
                            %values get sent to back of sample buffer, where they will
                            %be overwritten later (?)
                            sample_buffer = circshift(sample_buffer,-size(samples,2),2);
                            sample_buffer(:,end-size(samples,2)+1:end)=zeros(app.device.num_channels, size(samples,2));  %clear rotated samples
                            
                            %Write new samples before time "0"
                            sample_buffer(:, app.sampler.sample_rate-size(samples, 2) + 1:app.sampler.sample_rate) = samples;
                            
                            
                            %if the entire sample_buffer is not (yet) full with samples and
                            %event is happening
                        elseif((buffer_full(73))~=1&&(event_happening==1)&&index<app.sampler.sample_rate*3)
                            %update sample buffer from the left side of sample_rate
                            %(time0)
                            
                            sample_buffer(:, index :index-1+size(samples,2)) = samples;
                            
                            %update index location
                            index=index+size(samples,2);
                            
                            %if index exceeds 12000 or the sample_buffer is full (use only DIGI channel for simplicity)!
                        elseif((index>app.sampler.sample_rate*3)||(buffer_full(73))==1)
                            
                            index=app.sampler.sample_rate+1;             %reset index
                            
                            sample_buffer = sample_buffer(:, (end-window_samples+1):end);  %remove end of sample_buffer that is extra.
                            
                            event_happening=0;                       %reset event_happening flag so another event can be read into
                            %sample_buffer
                            
                            plot_flag=1;                             %Set plot flag
                            
                        else
                            disp('Error: main cases not met in BCI loop');
                        end


                        % When event occurs (digi<255), calculate average ERP and plot it
                        if(plot_flag==1)
                            
                            %reset plot_flag
                            plot_flag=0;


                            app.avgnum =  app.avgnum +1;
                            app.avgsum= app.avgsum + sample_buffer(:, 1:end);
                            count=0;
                            
                            %recalculate average
                            average= app.avgsum ./ app.avgnum;
                            
                            %   figure(Avg_fig_handle)
                             plot(ax, time, average(app.average_subset, :));
                            %set(app.UIAxes, Ydata, average(app.average_subset,:));
                            make_sound(app);
                            % drawnow
                            
                            %Reset of the rest of sample_buffer
                            sample_buffer(:,app.sampler.sample_rate+1:end)=zeros(app.device.num_channels,app.sampler.sample_rate*2);
                            
                            
                        end
                        
                                               
                        % Step 11: Append samples to plot.
                        realTimePlot.append(samples(app.channel_subset, :));
                        
                        % Step 12: Draw
                        poly5.append(samples(app.channel_subset, :));
                        realTimePlot.draw();
                    end


                    % Step 13: Close file.
                    poly5.close();
                    
                catch matlabException
                    warning(sprintf('Error while trying to sample.\n\tIdentifier: %s\n\tMessage: %s\n', matlabException.identifier, matlabException.message));
                end
                % Step 14: Stop sampler.
                app.sampler.stop();
                %turn lamp off
                  app.ConnectedLamp.Color= [1 0 0];
                  
                
                % Step 15: Disconnect with device.
                app.sampler.disconnect();
            catch matlabException
                warning(sprintf('Error while trying to create device/sampler.\n\tIdentifier: %s\n\tMessage: %s\n', matlabException.identifier, matlabException.message));
            end
            % Step 16: Cleanup library.
            app.library.destroy();
            
            %make it obvious that the file needs to be saved! 
            app.SaveFileButton.FontSize=20;
            app.SaveFileButton.BackgroundColor= [1 1 1];
            app.SaveFileButton.FontWeight= 'bold';
        end


        %saves the .poly5 file as .set and .fdt files for EEGLAB
        function convert_to_set(app)
      
            
          %  if fn==0 % no fn was given as input, open dialog instead
                
                [file, pathname] = uigetfile({'*.Poly5';'*.S00';'*.TMS32'},'Pick your file', app.file_name);
                
                
                [path,filename,extension] = fileparts(file);
                
                % check if file was selected, if not, stop.
                if isequal(filename,0) || isequal(pathname,0)
                    disp('File open dialog was cancelled')
                    return
                    
                end
                
            %else
                
              %  [pathname,filename,extension] = fileparts(fn);
                
          %  end
            
            %read in data file
            d = TMSi.Poly5.read([pathname,'\',filename,extension]);
            
            
            % Step 2: Plot a single channel.
            %plot((0:(d.num_samples - 1)) / d.sample_rate, d.samples(2, :));
            
            %  Step 3: Save dataset in the same directory as the *.Poly5 file.
            %   NOTE: Ensure eeglab path is correct and is run atleast once before calling this script.)
            
            if exist('eeglab','file')==0
                disp('EEGLAB not found. It is not installed properly or not present on the system. Download EEGlab and install according to instructions')
                return
            elseif exist('eeglab','file')==2
                
                % Transform data to eeglab.
                eegdataset = toEEGLab(d);
                
                % Save dataset in the same directory as the *.Poly5 file.
                pop_saveset(eegdataset,'filename',filename,'filepath',pathname)
                disp(['Data saved as EEGlab dataset (.set) in this folder: ',pathname])
            else
                disp('Something went wrong, eeglab dataset could not be saved.')
                return
            end
        end
            
        
        function connect(app)
            
            
            % Step 1: Setup library and choose connection type ( 'usb', 'bluetooth', 'network' or 'wifi' ).
            app.library = TMSi.Library('usb');
            
            % Step 2: Find device to connect to. Keep on trying every second.
            while numel(app.library.devices) == 0
                app.library.refreshDevices();
                pause(1);
            end
            
            try
                % Step 3: Get first device an retrieve information about device.
                app.device = app.library.getFirstDevice();
                
                % Step 4: Create a sampler with which we are going to retrieve samples.
                app.sampler = app.device.createSampler();
                
                try
                    % Step 5: Set settings for sampler.
                    % sampler.setSampleRate(2048);
                    % sampler.setReferenceCalculation(true);
                    
                    % Step 6: Create a RealTimePlot.
                    realTimePlot = TMSi.RealTimePlot('RealTimePlot Example', app.sampler.sample_rate, app.device.channels(app.channel_subset));
                    realTimePlot.setWindowSize(10);
                    realTimePlot.show();
                    
                    
                    % Step 8: Connect to device through the sampler.
                    app.sampler.connect();
                    
                    % Step 9: Start sampling.
                    app.sampler.start();
                      app.ConnectedLamp.Color= [0 1 0];
                    % Step 10: Sample as long as plot is visible. Can be closed with 'q' or cross.
                    while realTimePlot.is_visible
                        samples = app.sampler.sample();
                        
                        % Step 11: Append samples to plot.
                        realTimePlot.append(samples(app.channel_subset, :));
                        
                        % Step 12: Draw
                        realTimePlot.draw();
                    end
                catch matlabException
                    warning(sprintf('Error while trying to sample.\n\tIdentifier: %s\n\tMessage: %s\n', matlabException.identifier, matlabException.message));
                end
                % Step 14: Stop sampler.
                app.sampler.stop();
                app.ConnectedLamp.Color= [1 0 0];
                
                % Step 15: Disconnect with device.
                app.sampler.disconnect();
            catch matlabException
                warning(sprintf('Error while trying to create device/sampler.\n\tIdentifier: %s\n\tMessage: %s\n', matlabException.identifier, matlabException.message));
            end
            % Step 16: Cleanup library.
            app.library.destroy();
        end
        
        
            %disconnects the sampler from the eeg amplifier. 
            function disconnect_device(app)
                % Step 9: Stop sampler.
                app.sampler.stop();
                fprintf(' = Sampling stopped.\n');
                
                app.ConnectedLamp.Color= [1 0 0];
                
                % Step 10: Disconnect with device.
                app.sampler.disconnect();
                fprintf(' = Sampler disconnected.\n');
                
                % Step 11: Cleanup library.
                app.library.destroy();
                fprintf(' = Destroy library.\n');
            end


    %Takes the text fields from Participant ID and Trial # to create a unique filename. 
    function make_filename(app)
        
        ID_number=app.ParticipantIDEditField.Value;
        
        trial_number=app.TrialConditionEditField.Value;
        
        filename=strcat("Participant_",ID_number,"_","condition_", trial_number);
        
        app.FilenameEditField.Value=filename;
        
    end

    %a function where the user sets their path. 
    function change_path(~)
        
        newpath=uigetdir('Z:\19_Carson_Berry\EEG\MATLAB\data', 'Set working directory');
        userpath(newpath);
        
    end
    
    
    % A function to initialize a 5 minute timer that sounds 
    function init_thirty_timer(app)
        
        %init timer with period of 2 seconds, to run forever.
        
        app.thirty_timer = timer('Period',app.minor_minutes*60,...
            'ExecutionMode', 'fixedSpacing', ...
            'TasksToExecute', floor(app.major_minutes/app.minor_minutes)+1,... %execute an extra time for the start. 
            'StartDelay', 0);
        
            %every 5 minutes makes a sound "please rate your pain"
        app.thirty_timer.TimerFcn= @(~,~)app.make_sound;
        app.thirty_timer.StopFcn = @(~,~)app.TimerCleanup;
        
        %make lamp green
        app.Lamp.Color= [0 1 0];
        start(app.thirty_timer);
        
    end
    
    function TimerCleanup(app, thirty_timer, ~)
        %make lamp red
        app.Lamp.Color= [1 0 0];
        disp('Timer Stopped');
                delete(app.thirty_timer);
    end 
   
    
    function set_default_path(app)
       
        userpath(app.default_path);
        
        
    end
    
    %Reads audio file info into y, Fs
    function init_sound(app)
    [app.y, app.Fs] = audioread('rate_pain.mp3');
    end
    
    %Makes sound "Please Rate your Pain"
    function make_sound(app, thirty_timer, ~)
        sound(app.y, app.Fs)
        
    end
    
    %Live Average Logic
    function sample_buffer=init_live_average(app)
        
         % Average settings
    window_size = 3;
    window_sec = 3;
    num_blocks = window_size / window_sec;
    event_location=app.sampler.sample_rate; %want to check it after 1 second, and have the rest of the buffer (2 seconds) display
    
    %how many data points to graph 
    window_samples = floor(window_sec * app.sampler.sample_rate);
    sample_buffer = zeros(app.device.num_channels, window_samples);
    sample_buffer(73, :)=255;
        
    end
    
    
    end %end of methods/functions. 


    methods (Access = public)
    
        
        %A function to set the channel_subset property
        function set_channel_subset(app, subset_choice)
             
            switch subset_choice
                case '19 Channel'
                    app.channel_subset= [1 3 4 5 6 7 8 14 15 16 17 18 24 25 26 27 28 30 32 73 74];
                    
                case '32 Channel'
                    app.channel_subset= [1 2 3 4 5 6 7 8 9 10 11 12 14 15 16 17 18 20 21 22 23 24 25 26 27 28 29 30 31 32 73 74];
                
                case '64 Channel'
                    app.channel_subset= [1:64 73 74];
                case 'Custom'
                    
                    %open channel_select app
                    channel_select(app, app.channel_subset);
                    
%                        
%                             
%                         end


                        %case for returning from custom's call to channel_select recursively. 
                otherwise 
                    fprintf('%s ', subset_choice);
                    app.channel_subset=subset_choice;
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            %Process inputs
           % set_default_path(app);
            
            %read sound info
            init_sound(app);
            app.device.is_connected=false;
        end

        % Button pushed function: CheckImpedancesButton
        function CheckImpedancesButtonPushed(app, event)
            %Call dialog box to display impedances 
            ImpedanceCheck(app.channel_subset, app.FilenameEditField.Value);
                
        end

        % Value changed function: ChannelSubsetListBox
        function ChannelSubsetListBoxValueChanged(app, event)
            value = app.ChannelSubsetListBox.Value;
            
                    
            %change the channel subset property
            set_channel_subset(app, value);
            
            
        end

        % Button pushed function: ConnecttoEEGButton
        function ConnecttoEEGButtonPushed(app, event)
            connect(app);
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            record(app);
        end

        % Value changed function: ParticipantIDEditField
        function ParticipantIDEditFieldValueChanged(app, event)
            value = app.ParticipantIDEditField.Value;
            make_filename(app);
        end

        % Value changed function: TrialConditionEditField
        function TrialConditionEditFieldValueChanged(app, event)
            value = app.TrialConditionEditField.Value;
            make_filename(app);
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
                %make it obvious that the file needs to be saved! 
            app.SaveFileButton.FontSize=12;
            app.SaveFileButton.BackgroundColor= [0.96 0.96 0.96];
            app.SaveFileButton.FontWeight= 'normal';
            convert_to_set(app);
        end

        % Callback function
        function SetDirectoryButtonPushed(app, event)
            change_path(app);
        end

        % Button pushed function: soundtestButton
        function soundtestButtonPushed(app, event)
            make_sound(app);
        end

        % Button pushed function: Start30minTimerButton
        function Start30minTimerButtonPushed(app, event)
            init_thirty_timer(app);
        end

        % Close request function: RecordEEGUIFigure
        function RecordEEGUIFigureCloseRequest(app, event)
          
            TimerCleanup(app,app.thirty_timer, event);
            
           
           if (app.device.is_connected==true)
            disconnect_device(app);
           end 
           
            delete(app)
        end

        % Value changed function: PlotAveragesListBox
        function PlotAveragesListBoxValueChanged(app, event)
            value = app.PlotAveragesListBox.Value;
            
            app.average_subset=get_channel_number(app, value);
            
        end

        % Button pushed function: OpenEEGLABButton
        function OpenEEGLABButtonPushed(app, event)
            eeglab;
        end

        % Value changed function: EEGLABAnalysesDropDown
        function EEGLABAnalysesDropDownValueChanged(app, event)
    
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
                 processing = app.EEGLABAnalysesDropDown.Value;
            
            file_folder=app.FoldertoProcessEditField.Value;
            
        switch processing
            case 'Preprocess'
                process_EEG_folder(file_folder);
                
            case 'Load All Files'
                disp('Please open all files in EEGLAB window');
                disp('Inspect each file, looking for irregularities in the data');
                disp('Common irregularities include: ');
                fprintf('\t 1. The first event not being tied to a stimulus (related to device startup) \n');
                    fprintf('\t \t To investigate this potential issue - open Plot -> Data Channel (Scroll) \n');
                    fprintf('\t \t If there are 21 epochs and the first is very noisy- delete it \n');
                                   
               
                fprintf('\t 2. An electrode recording significant noise for one or more epochs');
                    fprintf('\t \t To investigate - open Plot -> Channel ERP image -> Channel # that you are concerned about \n');
                    
                disp('Once you are satisfied that each file is of good quality, please save the file in the same folder');
                    
            case 'ICA'
                runica_folder(file_folder);
                
            case 'Latency Plots'
                plot_latencies(file_folder, app.DominantHandDropDown.Value);
                
            otherwise 
                disp('Error Processing EEGLAB input');
        end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordEEGUIFigure = uifigure;
            app.RecordEEGUIFigure.Name = 'RecordEEG';
            app.RecordEEGUIFigure.CloseRequestFcn = createCallbackFcn(app, @RecordEEGUIFigureCloseRequest, true);
            app.FilenameEditFieldLabel = uilabel(app.RecordEEGUIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.RecordEEGUIFigure, 'text');
            app.FilenameEditField.Value = 'Trial_example';
            app.CheckImpedancesButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.CheckImpedancesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckImpedancesButtonPushed, true);
            app.CheckImpedancesButton.Text = 'Check Impedances';
            app.PlotAveragesListBoxLabel = uilabel(app.RecordEEGUIFigure);
            app.PlotAveragesListBoxLabel.Text = 'Plot Averages';
            app.PlotAveragesListBox = uilistbox(app.RecordEEGUIFigure);
            app.PlotAveragesListBox.Items = {'CZ', ' FP1', ' FPZ', 'FP2', ' F7', 'F3', 'FZ ', 'F4 ', 'F8', 'FC5', 'FC1', 'FC2', 'FC6', 'M1', 'T7', 'C3', 'C7', 'C4', 'T8', 'M2', 'CP5', 'CP1', 'CP2', 'CP6', 'P7', 'P3', 'PZ', 'P4', 'P8', 'POZ', 'O1', 'OZ', 'O2', 'AF7', 'AF3', 'AF4', 'AF8', 'F5', 'F1', 'F2', 'F6', 'FC3', 'FC7', 'FC4', 'C5', 'C1', 'C2', 'CP3', 'C6', 'CZ', 'CP4', 'P5', 'P1', 'P2', 'P6', 'PO5', 'PO3', 'PO4', 'PO6', 'FT7', 'FT8', 'TP7', 'TP8', 'PO7'};
            app.PlotAveragesListBox.ValueChangedFcn = createCallbackFcn(app, @PlotAveragesListBoxValueChanged, true);
            app.PlotAveragesListBox.Value = 'CZ';
            app.ChannelSubsetListBoxLabel = uilabel(app.RecordEEGUIFigure);
            app.ChannelSubsetListBoxLabel.Text = 'Channel Subset';
            app.ChannelSubsetListBox = uilistbox(app.RecordEEGUIFigure);
            app.ChannelSubsetListBox.Items = {'19 Channel', '32 Channel', '64 Channel', 'Custom'};
            app.ChannelSubsetListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelSubsetListBoxValueChanged, true);
            app.ChannelSubsetListBox.Value = '19 Channel';
            app.EEGLABAnalysesDropDownLabel = uilabel(app.RecordEEGUIFigure);
            app.EEGLABAnalysesDropDownLabel.Text = 'EEGLAB Analyses';
            app.EEGLABAnalysesDropDown = uidropdown(app.RecordEEGUIFigure);
            app.EEGLABAnalysesDropDown.Items = {'Preprocess ', 'Load All Files', 'ICA ', 'Latency Plots'};
            app.EEGLABAnalysesDropDown.ValueChangedFcn = createCallbackFcn(app, @EEGLABAnalysesDropDownValueChanged, true);
            app.EEGLABAnalysesDropDown.Value = 'Preprocess ';
            app.OpenEEGLABButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.OpenEEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @OpenEEGLABButtonPushed, true);
            app.OpenEEGLABButton.Text = 'Open EEGLAB';
            app.StartRecordingButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.ConnecttoEEGButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.ConnecttoEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoEEGButtonPushed, true);
            app.ConnecttoEEGButton.Text = 'Connect to EEG';
            app.ConnectedLamp = uilamp(app.RecordEEGUIFigure);
            app.ParticipantIDEditFieldLabel = uilabel(app.RecordEEGUIFigure);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.RecordEEGUIFigure, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDEditFieldValueChanged, true);
            app.ParticipantIDEditField.Value = 'C0';
            app.TrialConditionEditFieldLabel = uilabel(app.RecordEEGUIFigure);
            app.TrialConditionEditFieldLabel.Text = 'Trial Condition';
            app.TrialConditionEditField = uieditfield(app.RecordEEGUIFigure, 'text');
            app.TrialConditionEditField.ValueChangedFcn = createCallbackFcn(app, @TrialConditionEditFieldValueChanged, true);
            app.TrialConditionEditField.Value = '1';
            app.SaveFileButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.soundtestButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.soundtestButton.ButtonPushedFcn = createCallbackFcn(app, @soundtestButtonPushed, true);
            app.soundtestButton.Text = 'sound test';
            app.UIAxes = uiaxes(app.RecordEEGUIFigure);
            app.UIAxes.XLim = [0 12000];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.Start30minTimerButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.Start30minTimerButton.ButtonPushedFcn = createCallbackFcn(app, @Start30minTimerButtonPushed, true);
            app.Start30minTimerButton.Text = 'Start 30 min Timer';
            app.Lamp = uilamp(app.RecordEEGUIFigure);
            app.FoldertoProcessEditFieldLabel = uilabel(app.RecordEEGUIFigure);
            app.FoldertoProcessEditFieldLabel.Text = 'Folder to Process';
            app.FoldertoProcessEditField = uieditfield(app.RecordEEGUIFigure, 'text');
            app.GoButton = uibutton(app.RecordEEGUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.DominantHandDropDownLabel = uilabel(app.RecordEEGUIFigure);
            app.DominantHandDropDownLabel.Text = 'Dominant Hand';
            app.DominantHandDropDown = uidropdown(app.RecordEEGUIFigure);
            app.DominantHandDropDown.Items = {'Right', 'Left'};
            app.DominantHandDropDown.Value = 'Right';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecordEEG

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordEEGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordEEGUIFigure)
        end
    end
end